classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageSteganographyUIFigure     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenaJPEGimageMenu             matlab.ui.container.Menu
        OpenafiletoembedMenu           matlab.ui.container.Menu
        OpenaJPEGimagewithmessageMenu  matlab.ui.container.Menu
        SavethefinalimageMenu          matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        AboutthissoftwareMenu          matlab.ui.container.Menu
        BuildfinalimageButton          matlab.ui.control.Button
        FiletoembedEditFieldLabel      matlab.ui.control.Label
        FiletoembedEditField           matlab.ui.control.EditField
        Lamp                           matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        FileSizeEditFieldLabel         matlab.ui.control.Label
        FileSizeEditField              matlab.ui.control.NumericEditField
        ConfigurealgorithmDropDownLabel  matlab.ui.control.Label
        ConfigurealgorithmDropDown     matlab.ui.control.DropDown
        ByteLabel                      matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        CompareButton                  matlab.ui.control.Button
        RetrieveButton                 matlab.ui.control.Button
        MSEEditFieldLabel              matlab.ui.control.Label
        MSEEditField                   matlab.ui.control.NumericEditField
        SSIMEditFieldLabel             matlab.ui.control.Label
        SSIMEditField                  matlab.ui.control.NumericEditField
        PSNREditField_2Label           matlab.ui.control.Label
        PSNREditField                  matlab.ui.control.NumericEditField
        SNREditField_2Label            matlab.ui.control.Label
        SNREditField                   matlab.ui.control.NumericEditField
        HistogramButton                matlab.ui.control.Button
        dBLabel                        matlab.ui.control.Label
        dBLabel_2                      matlab.ui.control.Label
    end


    properties (Access = private)
        file_to_embed % file_to_embed object
        file_to_embed_name %Filename of the file the user wants to embed
        file_to_embed_path % Path of file_to_embed
        jpg_image % jpg_image object
        jpg_image_name % JPG image name that will contain the file_to_embed
        jpg_image_path % Path of jpgImage
        embedded_image_name %JPG image name with the file_to_embed
        embedded_image_path % Path of JPG image name with the file_to_embed
        embedded_image % embedded_image Object
        algorithm_name % String of the algorithm name selected
        figure_compare
        
    end


    methods (Access = private)

        % Menu selected function: OpenaJPEGimageMenu
        function OpenaJPEGimageMenuSelected(app, event)
            app.Lamp.Color = [1 0 0];           
            [app.jpg_image_name,app.jpg_image_path] = uigetfile('*.jpg');
            if  ne(app.jpg_image_name,0)
                % Display the image
                imshow(strcat(app.jpg_image_path,app.jpg_image_name), 'Parent', app.UIAxes) %,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                title(app.UIAxes, app.jpg_image_name)
                xlabel(app.UIAxes, [])
                ylabel(app.UIAxes, []) 
                
                % Calculate the payload           
                app.jpg_image=JPEGStegoObj([app.jpg_image_path,app.jpg_image_name]);
                Init_Image(app.jpg_image);
                
                Get_Payload_Capacity_BPCS(app.jpg_image);
                payloadBPCS=app.jpg_image.payloadCapacityBPCS;
                payloadBPCS=payloadBPCS*100;
                countNoiseAreasBPCS=app.jpg_image.countNoiseAreasInBytes;
                
                Get_Payload_Capacity_LSB(app.jpg_image);
                payloadLSB=app.jpg_image.payloadCapacityLSB;
                payloadLSB=payloadLSB*100;
                countLSBAreas=app.jpg_image.countLSBAreasInBytes*2;
                
                d = {'BPCS',payloadBPCS,countNoiseAreasBPCS;'LSB',payloadLSB,countLSBAreas};
                app.UITable.Data = d;
                
            end
            app.Lamp.Color = [0 1 0];
        end

        % Menu selected function: OpenafiletoembedMenu
        function OpenafiletoembedMenuSelected(app, event)
            [app.file_to_embed_name,app.file_to_embed_path] = uigetfile('.txt'); 
            
            %% Show name and size of file to hide
            
            if ne(app.file_to_embed_name,0)
                app.file_to_embed = EmbeddedObj(strcat(app.file_to_embed_path,app.file_to_embed_name));
                app.FiletoembedEditField.Value = app.file_to_embed.name;
                app.FileSizeEditField.Value = app.file_to_embed.size; 
                
                if gt(app.file_to_embed.size,1)
                    app.ByteLabel.Text = 'Bytes';
                end
                
            end
        end

        % Callback function
        function HidefileButtonValueChanged(app, event)
          
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close all;
            app.delete;
        end

        % Menu selected function: SavethefinalimageMenu
        function SavethefinalimageMenuSelected(app, event)
            [app.embedded_image,app.embedded_image_path] = uiputfile('*.JPG');
        end

        % Button pushed function: BuildfinalimageButton
        function BuildfinalimageButtonPushed(app, event)
            app.Lamp.Color = [1 0 0];
            
            %% Alerts
            if isequal(app.jpg_image_name,[]) || isequal(app.jpg_image_name,0)
                f = uifigure;
                uialert(f,'You must select an image','Invalid Selection');
            elseif isequal(app.file_to_embed_name,[]) || isequal(app.file_to_embed_name,0)
                f = uifigure;
                uialert(f,'You must select a file to embed','Invalid Selection');
            else        
                
                %% Algorithm comparison
                
                if isequal(app.algorithm_name,'BPCS') 
                    if gt(app.jpg_image.countNoiseAreasInBytes,app.file_to_embed.size) 
                        Apply_BPCS(app.jpg_image, strcat(app.file_to_embed_path,app.file_to_embed_name));
                        app.embedded_image_path = app.jpg_image_path;
                        app.embedded_image_name = app.jpg_image_name;
                        app.embedded_image=app.jpg_image;
                        app.FileSizeEditField.Value = app.file_to_embed.size; 
                        app.PSNREditField.Value = app.embedded_image.Peak_SNR; 
                        app.SNREditField.Value = app.embedded_image.SNR;
                        app.MSEEditField.Value = app.embedded_image.MSE;
                        app.SSIMEditField.Value = app.embedded_image.SSIMVal;
                    else 
                        f = uifigure;
                        uialert(f,"The file's information to hide exceed the the image payload capacity.",'Invalid Size');
                    end
                elseif isequal(app.algorithm_name,'LSB')
                    if gt(app.jpg_image.countLSBAreasInBytes,app.file_to_embed.size)
                        Apply_LSB(app.jpg_image, strcat(app.file_to_embed_path,app.file_to_embed_name));
                        app.embedded_image_path = app.jpg_image_path;
                        app.embedded_image_name = app.jpg_image_name;
                        app.embedded_image=app.jpg_image;
                        app.PSNREditField.Value = app.embedded_image.Peak_SNR; 
                        app.SNREditField.Value = app.embedded_image.SNR;
                        app.MSEEditField.Value = app.embedded_image.MSE;
                        app.SSIMEditField.Value = app.embedded_image.SSIMVal;
                    else
                        f = uifigure;
                        uialert(f,"The file's information to hide exceed the the image payload capacity.",'Invalid Size');
                    end 
                else 
                    %% Invalid algorithm alert
                    f = uifigure;
                    uialert(f,'You must select an algorithm','Invalid Selection');
                end
            end
            pause(1);
            app.Lamp.Color = [0 1 0];
        end

        % Value changed function: ConfigurealgorithmDropDown
        function ConfigurealgorithmDropDownValueChanged(app, event)
            app.algorithm_name = app.ConfigurealgorithmDropDown.Value;
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            if isequal(app.algorithm_name,'BPCS')
                figure('Name','JPG images comparison')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-BPCS.jpg')),'montage');
                figure('Name','JPG images difference')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-BPCS.jpg')),'diff');
            elseif isequal(app.algorithm_name,'LSB')
                figure('Name','JPG images comparison')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-LSB.jpg')),'montage');
                figure('Name','JPG images difference')
                imshowpair(imread([app.jpg_image_path,app.jpg_image_name]),imread(strcat(app.jpg_image.name,'-embedded-LSB.jpg')),'diff');
            end
        end

        % Button pushed function: RetrieveButton
        function RetrieveButtonPushed(app, event)
            
            %%Retrieve file from stego image
            
            app.Lamp.Color = [1 0 0];
            if isequal(app.embedded_image_name,[]) || isequal(app.embedded_image_name,0)
                f = uifigure;
                uialert(f,'You must have selected a JPEG image with an embedded message to perform this','JPEG image missing');
            elseif  isequal(app.algorithm_name,'BPCS')
                Retrieve_Data_from_BPCS(app.embedded_image);
            elseif isequal(app.algorithm_name,'LSB')
                Retrieve_Data_from_LSB(app.embedded_image);
            end
            pause(1);
            app.Lamp.Color = [0 1 0];
        end

        % Menu selected function: OpenaJPEGimagewithmessageMenu
        function OpenaJPEGimagewithmessageMenuSelected(app, event)
            
            %%Open stego image
            
            app.Lamp.Color = [1 0 0];
            [app.embedded_image_name,app.embedded_image_path] = uigetfile('*.jpg');
            if  ne(app.embedded_image_name,0)
                % Display the image
                imshow(strcat(app.embedded_image_path,app.embedded_image_name), 'Parent', app.UIAxes) %,'XData', [1 app.UIAxes.Position(3)],'YData', [1 app.UIAxes.Position(4)]);
                title(app.UIAxes, app.embedded_image_name);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.embedded_image=JPEGStegoObj(strcat(app.embedded_image_path,app.embedded_image_name));
                app.PSNREditField.Value = app.embedded_image.Peak_SNR; 
                app.SNREditField.Value = app.embedded_image.SNR;
                app.MSEEditField.Value = app.embedded_image.MSE;
                app.SSIMEditField.Value = app.embedded_image.SSIMVal;
            end
            %Lamp color
            app.Lamp.Color = [0 1 0];
        end

        % Menu selected function: AboutthissoftwareMenu
        function AboutthissoftwareMenuSelected(app, event)
            
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            Complexity_Histogram(app.embedded_image)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageSteganographyUIFigure = uifigure;
            app.ImageSteganographyUIFigure.Name = 'Image Stegnography';
            app.FileMenu = uimenu(app.ImageSteganographyUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenaJPEGimageMenu = uimenu(app.FileMenu);
            app.OpenaJPEGimageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenaJPEGimageMenuSelected, true);
            app.OpenaJPEGimageMenu.Accelerator = 'o';
            app.OpenaJPEGimageMenu.Text = 'Open a JPEG image...';
            app.OpenafiletoembedMenu = uimenu(app.FileMenu);
            app.OpenafiletoembedMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenafiletoembedMenuSelected, true);
            app.OpenafiletoembedMenu.Accelerator = 'e';
            app.OpenafiletoembedMenu.Text = 'Open a file to embed...';
            app.OpenaJPEGimagewithmessageMenu = uimenu(app.FileMenu);
            app.OpenaJPEGimagewithmessageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenaJPEGimagewithmessageMenuSelected, true);
            app.OpenaJPEGimagewithmessageMenu.Text = 'Open a JPEG image with message';
            app.SavethefinalimageMenu = uimenu(app.FileMenu);
            app.SavethefinalimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SavethefinalimageMenuSelected, true);
            app.SavethefinalimageMenu.Enable = 'off';
            app.SavethefinalimageMenu.Separator = 'on';
            app.SavethefinalimageMenu.Accelerator = 'S';
            app.SavethefinalimageMenu.Text = 'Save the final image...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.AboutMenu = uimenu(app.ImageSteganographyUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutthissoftwareMenu = uimenu(app.AboutMenu);
            app.AboutthissoftwareMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutthissoftwareMenuSelected, true);
            app.AboutthissoftwareMenu.Text = 'About this software';
            app.BuildfinalimageButton = uibutton(app.ImageSteganographyUIFigure, 'push');
            app.BuildfinalimageButton.ButtonPushedFcn = createCallbackFcn(app, @BuildfinalimageButtonPushed, true);
            app.BuildfinalimageButton.Text = 'Build final image';
            app.FiletoembedEditFieldLabel = uilabel(app.ImageSteganographyUIFigure);
            app.FiletoembedEditFieldLabel.Text = 'File to embed';
            app.FiletoembedEditField = uieditfield(app.ImageSteganographyUIFigure, 'text');
            app.FiletoembedEditField.Editable = 'off';
            app.Lamp = uilamp(app.ImageSteganographyUIFigure);
            app.UIAxes = uiaxes(app.ImageSteganographyUIFigure);
            title(app.UIAxes, 'Image')
            app.FileSizeEditFieldLabel = uilabel(app.ImageSteganographyUIFigure);
            app.FileSizeEditFieldLabel.Text = 'File Size';
            app.FileSizeEditField = uieditfield(app.ImageSteganographyUIFigure, 'numeric');
            app.FileSizeEditField.Limits = [0 Inf];
            app.FileSizeEditField.Editable = 'off';
            app.ConfigurealgorithmDropDownLabel = uilabel(app.ImageSteganographyUIFigure);
            app.ConfigurealgorithmDropDownLabel.Text = 'Configure algorithm';
            app.ConfigurealgorithmDropDown = uidropdown(app.ImageSteganographyUIFigure);
            app.ConfigurealgorithmDropDown.Items = {'Select algorithm', 'BPCS', 'LSB'};
            app.ConfigurealgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigurealgorithmDropDownValueChanged, true);
            app.ConfigurealgorithmDropDown.Value = 'Select algorithm';
            app.ByteLabel = uilabel(app.ImageSteganographyUIFigure);
            app.ByteLabel.Text = 'Byte';
            app.UITable = uitable(app.ImageSteganographyUIFigure);
            app.UITable.ColumnName = {'Algorithm'; 'Payload Capacity %'; 'Areas (bytes)'};
            app.UITable.RowName = {};
            app.CompareButton = uibutton(app.ImageSteganographyUIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.RetrieveButton = uibutton(app.ImageSteganographyUIFigure, 'push');
            app.RetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPushed, true);
            app.RetrieveButton.Text = 'Retrieve';
            app.MSEEditFieldLabel = uilabel(app.ImageSteganographyUIFigure);
            app.MSEEditFieldLabel.Text = 'MSE';
            app.MSEEditField = uieditfield(app.ImageSteganographyUIFigure, 'numeric');
            app.MSEEditField.Editable = 'off';
            app.SSIMEditFieldLabel = uilabel(app.ImageSteganographyUIFigure);
            app.SSIMEditFieldLabel.Text = 'SSIM';
            app.SSIMEditField = uieditfield(app.ImageSteganographyUIFigure, 'numeric');
            app.SSIMEditField.Editable = 'off';
            app.PSNREditField_2Label = uilabel(app.ImageSteganographyUIFigure);
            app.PSNREditField_2Label.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.ImageSteganographyUIFigure, 'numeric');
            app.PSNREditField.Editable = 'off';
            app.SNREditField_2Label = uilabel(app.ImageSteganographyUIFigure);
            app.SNREditField_2Label.Text = 'SNR';
            app.SNREditField = uieditfield(app.ImageSteganographyUIFigure, 'numeric');
            app.SNREditField.Editable = 'off';
            app.HistogramButton = uibutton(app.ImageSteganographyUIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.dBLabel = uilabel(app.ImageSteganographyUIFigure);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.ImageSteganographyUIFigure);
            app.dBLabel_2.Text = 'dB';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageSteganographyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageSteganographyUIFigure)
        end
    end
end